classdef interfaz2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HomeButton                    matlab.ui.control.Button
        YawEditFieldLabel             matlab.ui.control.Label
        PitchEditFieldLabel           matlab.ui.control.Label
        RollEditFieldLabel            matlab.ui.control.Label
        VelocidadActualLabel          matlab.ui.control.Label
        Vel_Actual_Z                  matlab.ui.control.NumericEditField
        Vel_Actual_Y                  matlab.ui.control.NumericEditField
        Vel_Actual_X                  matlab.ui.control.NumericEditField
        PosicinActualLabel            matlab.ui.control.Label
        Posicion_Actual_Z             matlab.ui.control.NumericEditField
        Posicion_Actual_Y             matlab.ui.control.NumericEditField
        Posicion_Actual_X             matlab.ui.control.NumericEditField
        ZLabel                        matlab.ui.control.Label
        IraposicionButton             matlab.ui.control.Button
        SeleccionarcooordenadasLabel  matlab.ui.control.Label
        YawEditField                  matlab.ui.control.NumericEditField
        PitchEditField                matlab.ui.control.NumericEditField
        RollEditField                 matlab.ui.control.NumericEditField
        ZEditField                    matlab.ui.control.NumericEditField
        YEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        SistemaRemotoCheckBox         matlab.ui.control.CheckBox
        SistemaLocalCheckBox          matlab.ui.control.CheckBox
        ControlSwitch                 matlab.ui.control.RockerSwitch
        ControlSwitchLabel            matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        PosicinMenu                   matlab.ui.container.Menu
        VelocidadMenu                 matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        X_value = 0;
        Y_value = 0;
        Z_value = 0;
        Row = 0;
        Pitch = 0;
        Yaw = 0;
        actual_pos = 0;
        
        
        timer % Description
        sub % Description
        pub
        msg
        actClient % Description
        goalMsg % Description
        actual_pos = [0 0 0]% Description
        positions = []
        vels = []
        phSpeed = []
        run = false
        speed_control = false;
        speed_factor = 3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            display('START')
            app.StopButton.Enable = 'on';
            app.StartButton.Enable = 'off';
            app.run = true;
            % Start ros conection
            startRos(app)
            % Start a timer to publish kinova speed if speed control is
            % activated
            if (app.speed_control)
                app.timer =  timer('Name','VelocityPublisher',           ...
                       'Period',0.001,                   ... 
                       'StartDelay',0,                 ... 
                       'TasksToExecute',inf,           ... 
                       'ExecutionMode','fixedRate', ...
                           'TimerFcn',{@timerCallback, app});
                start(app.timer)
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            display('STOP')
            app.StopButton.Enable = 'off';
            app.StartButton.Enable = 'on';
            app.run = false;
            stop(app.timer)
            Stop = app.StartButton.ButtonPushedFcn;
            clear; close; clc;
        end

        % Button pushed function: IraposicionButton
        function IraposicionButtonPushed(app, event)
            if (app.run)
                % Create ros action with a position goal
                moveTo( ...
                    app.actClient, ...
                    app.goalMsg, ...
                    [app.X_value app.Y_value app.Z_value], ...
                    [app.Row app.Pitch app.Yaw] ...
                );
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.X_value = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.Y_value = app.YEditField.Value;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.Z_value = app.ZEditField.Value; 
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
     
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            app.Row = app.RollEditField.Value * pi / 180;
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            app.Pitch = app.PitchEditField.Value * pi / 180;
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            app.Yaw = app.YawEditField.Value * pi / 180;
        end

        % Value changed function: ControlSwitch
        function ControlSwitchValueChanged(app, event)
            value = app.ControlSwitch.Value;
            if value == "Velocidad"
                app.speed_control = true;
                app.IraposicionButton.Enable = "off";
                app.HomeButton.Enable = "off";
                app.timer =  timer('Name','VelocityPublisher',           ...
                       'Period',0.001,                   ... 
                       'StartDelay',0,                 ... 
                       'TasksToExecute',inf,           ... 
                       'ExecutionMode','fixedRate', ...
                           'TimerFcn',{@timerCallback, app});
                start(app.timer)
            else
                app.speed_control = false;
                app.IraposicionButton.Enable = "on";
                app.HomeButton.Enable = "on";
                stop(app.timer)
            end
            
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            moveTo(app.actClient, app.goalMsg, [0.20991 -0.26187 0.47616], [89.4068298 65.609422 -1.8842576]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Posición-X-Y-Z')
            xlabel(app.UIAxes, 'T')
            ylabel(app.UIAxes, 'd (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, {'Velocidad'; ''})
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'gris-claro.jpg';
            app.ControlSwitchLabel = uilabel(app.UIFigure);
            app.ControlSwitchLabel.Text = 'Control';
            app.ControlSwitch = uiswitch(app.UIFigure, 'rocker');
            app.ControlSwitch.Items = {'Posición', 'Velocidad'};
            app.ControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValueChanged, true);
            app.ControlSwitch.Value = 'Posición';
            app.SistemaLocalCheckBox = uicheckbox(app.UIFigure);
            app.SistemaLocalCheckBox.Text = {'Sistema Local'; ''};
            app.SistemaRemotoCheckBox = uicheckbox(app.UIFigure);
            app.SistemaRemotoCheckBox.Text = 'Sistema Remoto';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'button1-removebg-preview.png';
            app.StartButton.Tooltip = {'if(1)'};
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'image-removebg-preview.png';
            app.StopButton.Tooltip = {'if(1)'};
            app.StopButton.Text = 'Stop';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = {'Y'; ''};
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.SeleccionarcooordenadasLabel = uilabel(app.UIFigure);
            app.SeleccionarcooordenadasLabel.Text = {'Seleccionar '; 'cooordenadas'; ''};
            app.IraposicionButton = uibutton(app.UIFigure, 'push');
            app.IraposicionButton.ButtonPushedFcn = createCallbackFcn(app, @IraposicionButtonPushed, true);
            app.IraposicionButton.Text = {'Ir a posición'; ''};
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.Posicion_Actual_X = uieditfield(app.UIFigure, 'numeric');
            app.Posicion_Actual_X.Editable = 'off';
            app.Posicion_Actual_Y = uieditfield(app.UIFigure, 'numeric');
            app.Posicion_Actual_Y.Editable = 'off';
            app.Posicion_Actual_Z = uieditfield(app.UIFigure, 'numeric');
            app.Posicion_Actual_Z.Editable = 'off';
            app.PosicinActualLabel = uilabel(app.UIFigure);
            app.PosicinActualLabel.Text = {'Posición '; 'Actual'};
            app.Vel_Actual_X = uieditfield(app.UIFigure, 'numeric');
            app.Vel_Actual_X.Editable = 'off';
            app.Vel_Actual_Y = uieditfield(app.UIFigure, 'numeric');
            app.Vel_Actual_Y.Editable = 'off';
            app.Vel_Actual_Z = uieditfield(app.UIFigure, 'numeric');
            app.Vel_Actual_Z.Editable = 'off';
            app.VelocidadActualLabel = uilabel(app.UIFigure);
            app.VelocidadActualLabel.Text = {'Velocidad '; 'Actual'};
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.PosicinMenu = uimenu(app.ContextMenu);
            app.PosicinMenu.Text = 'Posición';
            app.VelocidadMenu = uimenu(app.ContextMenu);
            app.VelocidadMenu.Text = 'Velocidad';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.UIAxes_2.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu2);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu2);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.ZEditField.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end